classdef CR_LeNet5v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        ChooseImageButton            matlab.ui.control.Button
        CertaintyLabel               matlab.ui.control.Label
        ProvidedbyAmirEbrahimiLabel  matlab.ui.control.Label
        CharacterrecognitionusingLeNet5Label  matlab.ui.control.Label
        StatusLampLabel              matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        Image                        matlab.ui.control.Image
        Label                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StatusLamp.Color=[1, 0, 0];
            I=imread("Newcastle.jpg");
            app.UIAxes.Selected=1;
            imshow(I,"Parent",app.UIAxes)
            layers=lenet5TLfun();
            net=assembleNetwork(layers);
            setappdata(app.UIFigure,'net',net)
            app.StatusLamp.Color=[0, 1, 0];
        end

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            try
            [file,path] = uigetfile({'*.png;*.jpg;*.bmp;*.tif','Supported images';...
                 '*.png','Portable Network Graphics (*.png)';...
                 '*.jpg','J-PEG (*.jpg)';...
                 '*.bmp','Bitmap (*.bmp)';...
                 '*.tif','Tagged Image File (*.tif,)';...
                 '*.*','All files (*.*)'},'Select an image');
            if (file~=0)
                app.StatusLamp.Color=[1, 0, 0];
                I = imread([path file]);
                if size(I,3)~=1
                    I=rgb2gray(I);
                end
                I = imresize(I,[32 32],'Colormap','original');
                if mean(I,"all")>128
                    I=255-I;
                end
                app.UIAxes.Selected=1;
                imshow(I,"Parent",app.UIAxes)
                net=getappdata(app.UIFigure,'net');
                [YPred,probs] = classify(net,I);
                app.Label.Text=string(YPred);
                app.CertaintyLabel.Text=strcat("Certainty: ", string(max(probs)*100), "%");
                app.StatusLamp.Color=[0, 1, 0];
            end
            catch ME
                msgbox(ME.message,"Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChooseImageButton = uibutton(app.UIFigure, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Tooltip = {'Select your image here'};
            app.ChooseImageButton.Text = 'Choose Image';
            app.CertaintyLabel = uilabel(app.UIFigure);
            app.CertaintyLabel.Tooltip = {'The level of certainty'};
            app.CertaintyLabel.Text = 'Certainty: ';
            app.ProvidedbyAmirEbrahimiLabel = uilabel(app.UIFigure);
            app.ProvidedbyAmirEbrahimiLabel.Text = {'Provided by:'; 'Amir Ebrahimi'};
            app.CharacterrecognitionusingLeNet5Label = uilabel(app.UIFigure);
            app.CharacterrecognitionusingLeNet5Label.Text = 'Character recognition using LeNet-5';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status: ';
            app.StatusLamp = uilamp(app.UIFigure);
            app.StatusLamp.Tooltip = {'Red: Processing'; 'Green: Ready'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Newcastle.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR_LeNet5v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end